classdef Umbilical_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UpdateumbilicalnodesButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    % The properties function works as the self properties in
    % python classes
    properties (Access = private)
        SelectedIndex (1,1) = nan;
        Linea
        PointInQuestion (1,1) logical = false;
        lc % length of cable in a variable available for all functions
        l0 % length of segments in a variable available for all functions
        n % # of nodes in a variable available for all functions
        npos % Position of nodes in a variable available for all functions
        x_temp % Temporary storage
        y_temp
        xax
        yax
    end
    
    methods (Access = private)
        
        function ChosenPoint(app, event)
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                   (event.Source.XData-event.IntersectionPoint(1)).^2+...
                   (event.Source.YData-event.IntersectionPoint(2)).^2); 
            end
        end

%         function updateFcn(app, ROTV_ini_pos, nodes)
%             app.n = nodes; % Define the # of nodes
% %             lc0 = [0 0 0; r];
% %             lc0(1,:) = [];
% %             lc0(end,:) = [];
% %             lc1 = r;
% %             lc1(1,:) = [];
% %             app.lc =  sqrt(sum((lc0-lc1).^2,2)); % Umbilical cable length in meters
% %             app.l0 = app.lc/nodes; % Umbilical cable segments length in meters
%             app.npos = zeros(nodes,2); % Initialize the nodes
%             
%             for i=1:nodes
%                 app.npos(i+1,:) = app.npos(i,:) + [ROTV_ini_pos(1)/nodes,ROTV_ini_pos(2)/nodes]; % Use to define ROTV intial position in regard to vessel
%             end
%             app.Linea = line(app.UIAxes,app.npos(:,1),app.npos(:,2),'Marker','o');
%             app.Linea.ButtonDownFcn = createCallbackFcn(app, @ChosenPoint, true);
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ROTV_ini_pos, nodes)
            app.n = nodes; % Define the # of nodes
            app.lc = norm(ROTV_ini_pos); % Umbilical cable length in meters
            app.l0 = app.lc/(nodes-1); % Umbilical cable segments length in meters
            app.npos = zeros(nodes,2); % Initialize the nodes
            for i=1:nodes-1
                app.npos(i+1,:) = app.npos(i,:) + [ROTV_ini_pos(1)/(nodes-1),ROTV_ini_pos(2)/(nodes-1)]; % Use to define ROTV intial position in regard to vessel
            end
            app.Linea = line(app.UIAxes,app.npos(:,1),app.npos(:,2),'Marker','o');
            app.Linea.ButtonDownFcn = createCallbackFcn(app, @ChosenPoint, true);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isnan(app.SelectedIndex)
                p = event.Source.CurrentPoint;
                app.x_temp = app.Linea.XData(app.SelectedIndex);
                app.y_temp = app.Linea.YData(app.SelectedIndex);
                x = p(1);
                y = p(2);
                x1 = app.UIAxes.InnerPosition(1);
                x2 = sum(app.UIAxes.InnerPosition([1 3]));
                y1 = app.UIAxes.InnerPosition(2);
                y2 = sum(app.UIAxes.InnerPosition([2 4]));
                if x>x1 && x<x2 && y>y1 && y<y2
                    xl = app.UIAxes.XLim;
                    yl = app.UIAxes.YLim;
                    app.xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                    app.yax = yl(1)+(yl(2)-yl(1))*(y-y1)/(y2-y1);
                    app.Linea.XData(app.SelectedIndex) = app.xax;
                    app.Linea.YData(app.SelectedIndex) = app.yax;
                    for i=app.SelectedIndex+1:1:length(app.npos)
                        app.Linea.XData(i) = app.Linea.XData(i) - (app.x_temp-app.xax);
                        app.Linea.YData(i) = app.Linea.YData(i) - (app.y_temp-app.yax);
                    end
%                     if app.l0 < norm([app.Linea.XData(app.SelectedIndex)-app.Linea.XData(app.SelectedIndex-1);app.Linea.YData(app.SelectedIndex)-app.Linea.YData(app.SelectedIndex-1)])
%                         P = [(xax-xold);(yax-yold)];
%                         Q = (app.l0/norm(P))*P;
%                         app.Linea.XData(app.SelectedIndex) = Q(1) + xold;
%                         app.Linea.YData(app.SelectedIndex) = Q(2) + yold;
%                         for i=app.SelectedIndex+1:1:length(app.npos)
%                             app.Linea.XData(i) = app.Linea.XData(i) - (xold-app.Linea.XData(app.SelectedIndex));
%                             app.Linea.YData(i) = app.Linea.YData(i) - (yold-app.Linea.YData(app.SelectedIndex));
%                         end
%                     end
%                     line(app.UIAxes,xax,yax,'marker','o');
                    xlim(app.UIAxes,xl);
                    ylim(app.UIAxes,yl);
                end
            end

        end

        % Button pushed function: UpdateumbilicalnodesButton
        function UpdateumbilicalnodesButtonPushed(app, event)
            assignin('base', 'GUIgen_init', [app.Linea.XData(:),zeros(length(app.npos),1),-app.Linea.YData(:)])
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isnan(app.SelectedIndex)
                if ~(app.l0 == norm([app.Linea.XData(app.SelectedIndex)-app.Linea.XData(app.SelectedIndex-1);app.Linea.YData(app.SelectedIndex)-app.Linea.YData(app.SelectedIndex-1)]))
                    xl = app.UIAxes.XLim;
                    yl = app.UIAxes.YLim;
                    xm1 = app.Linea.XData(app.SelectedIndex-1);
                    ym1 = app.Linea.YData(app.SelectedIndex-1);
                    P = [(app.Linea.XData(app.SelectedIndex)-xm1);(app.Linea.YData(app.SelectedIndex)-ym1)];
                    Q = (app.l0/norm(P))*P;
                    app.Linea.XData(app.SelectedIndex) = xm1 + Q(1);
                    app.Linea.YData(app.SelectedIndex) = ym1 + Q(2);
                    for i=app.SelectedIndex+1:1:length(app.npos)
                        app.Linea.XData(i) = app.Linea.XData(i) - (P(1) - Q(1));
                        app.Linea.YData(i) = app.Linea.YData(i) - (P(2) - Q(2));
                    end
                    xlim(app.UIAxes,xl);
                    ylim(app.UIAxes,yl);
                end
                app.SelectedIndex = nan;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Umbilical cable initial postition')
            xlabel(app.UIAxes, 'North')
            ylabel(app.UIAxes, 'Down')
            zlabel(app.UIAxes, 'Y')
            app.UpdateumbilicalnodesButton = uibutton(app.UIFigure, 'push');
            app.UpdateumbilicalnodesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateumbilicalnodesButtonPushed, true);
            app.UpdateumbilicalnodesButton.Text = 'Update umbilical nodes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Umbilical_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end